classdef Lasynth1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TriggerNoteButton    matlab.ui.control.Button
        LoadWAVButton        matlab.ui.control.Button
        RSlider              matlab.ui.control.Slider
        RSliderLabel         matlab.ui.control.Label
        SSlider              matlab.ui.control.Slider
        SSliderLabel         matlab.ui.control.Label
        DSlider              matlab.ui.control.Slider
        DSliderLabel         matlab.ui.control.Label
        ASlider              matlab.ui.control.Slider
        ASliderLabel         matlab.ui.control.Label
        TAILLENKnob          matlab.ui.control.Knob
        TAILLENKnobLabel     matlab.ui.control.Label
        CROSSFADEmsKnob      matlab.ui.control.Knob
        CROSSFADEmsLabel     matlab.ui.control.Label
        BRIGHTNESSKnob       matlab.ui.control.Knob
        BRIGHTNESSKnobLabel  matlab.ui.control.Label
        LAMIXKnob            matlab.ui.control.Knob
        LAMIXKnobLabel       matlab.ui.control.Label
        BASEFREQHzKnob       matlab.ui.control.Knob
        BASEFREQHzLabel      matlab.ui.control.Label
        SAMPLETRIMKnob       matlab.ui.control.Knob
        SAMPLETRIMLabel      matlab.ui.control.Label
        BottomUIAxes         matlab.ui.control.UIAxes
        MiddleUIAxes         matlab.ui.control.UIAxes
        TopUIAxes            matlab.ui.control.UIAxes
    end

    
properties (Access = private)
        WavFilePath = '';
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadWAVButton
        function LoadWAVButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav', 'WAV Audio Files (*.wav)'}, 'Select a sample');
            if ~isequal(file, 0)
                app.WavFilePath = fullfile(path, file);
                app.LoadWAVButton.Text = file;
            end
      
        end

        % Button pushed function: TriggerNoteButton
        function TriggerNoteButtonPushed(app, event)
            if isempty(app.WavFilePath)
                uialert(app.UIFigure, 'Please load a .wav file first.', 'No File Selected');
                return;
            end

            trim = app.SAMPLETRIMKnob.Value;
            freq = app.BASEFREQHzKnob.Value;
            mix  = app.LAMIXKnob.Value;

            A = app.ASlider.Value;
            D = app.DSlider.Value;
            S = app.SSlider.Value;
            R = app.RSlider.Value;

            brightness  = app.BRIGHTNESSKnob.Value;
            crossfadeMs = app.CROSSFADEmsKnob.Value;
            tailLen     = app.TAILLENKnob.Value;

            [attack, tail, combined, Fs] = processLASynth(app.WavFilePath, trim, freq, mix, A, D, S, R, brightness, crossfadeMs, tailLen);

            plot(app.TopUIAxes, attack);
            plot(app.MiddleUIAxes, tail);
            plot(app.BottomUIAxes, combined);
            sound(combined, Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TopUIAxes = uiaxes(app.UIFigure);
            xlabel(app.TopUIAxes, 'X')
            ylabel(app.TopUIAxes, 'Y')
            zlabel(app.TopUIAxes, 'Z')
            app.MiddleUIAxes = uiaxes(app.UIFigure);
            xlabel(app.MiddleUIAxes, 'X')
            ylabel(app.MiddleUIAxes, 'Y')
            zlabel(app.MiddleUIAxes, 'Z')
            app.BottomUIAxes = uiaxes(app.UIFigure);
            xlabel(app.BottomUIAxes, 'X')
            ylabel(app.BottomUIAxes, 'Y')
            zlabel(app.BottomUIAxes, 'Z')
            app.SAMPLETRIMLabel = uilabel(app.UIFigure);
            app.SAMPLETRIMLabel.Text = 'SAMPLE TRIM ';
            app.SAMPLETRIMKnob = uiknob(app.UIFigure, 'continuous');
            app.SAMPLETRIMKnob.Limits = [0.01 0.5];
            app.SAMPLETRIMKnob.Value = 0.15;
            app.BASEFREQHzLabel = uilabel(app.UIFigure);
            app.BASEFREQHzLabel.Text = 'BASE FREQ Hz';
            app.BASEFREQHzKnob = uiknob(app.UIFigure, 'continuous');
            app.BASEFREQHzKnob.Limits = [20 1000];
            app.BASEFREQHzKnob.Value = 110;
            app.LAMIXKnobLabel = uilabel(app.UIFigure);
            app.LAMIXKnobLabel.Text = 'LAMIX';
            app.LAMIXKnob = uiknob(app.UIFigure, 'continuous');
            app.LAMIXKnob.Limits = [0 2];
            app.LAMIXKnob.Value = 1;
            app.BRIGHTNESSKnobLabel = uilabel(app.UIFigure);
            app.BRIGHTNESSKnobLabel.Text = 'BRIGHTNESS';
            app.BRIGHTNESSKnob = uiknob(app.UIFigure, 'continuous');
            app.BRIGHTNESSKnob.Limits = [0.5 4];
            app.BRIGHTNESSKnob.Value = 1.5;
            app.CROSSFADEmsLabel = uilabel(app.UIFigure);
            app.CROSSFADEmsLabel.Text = 'CROSSFADE ms';
            app.CROSSFADEmsKnob = uiknob(app.UIFigure, 'continuous');
            app.CROSSFADEmsKnob.Limits = [1 100];
            app.CROSSFADEmsKnob.MajorTicks = [1 51 100];
            app.CROSSFADEmsKnob.Value = 20;
            app.TAILLENKnobLabel = uilabel(app.UIFigure);
            app.TAILLENKnobLabel.Text = 'TAILLEN';
            app.TAILLENKnob = uiknob(app.UIFigure, 'continuous');
            app.TAILLENKnob.Limits = [0.5 8];
            app.TAILLENKnob.Value = 4;
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [0 2];
            app.ASlider.Value = 0.01;
            app.DSliderLabel = uilabel(app.UIFigure);
            app.DSliderLabel.Text = 'D';
            app.DSlider = uislider(app.UIFigure);
            app.DSlider.Limits = [0 5];
            app.DSlider.Value = 2.6;
            app.SSliderLabel = uilabel(app.UIFigure);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.UIFigure);
            app.SSlider.Limits = [0 1];
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [0 2];
            app.RSlider.Step = 0.2;
            app.RSlider.Value = 0.5;
            app.LoadWAVButton = uibutton(app.UIFigure, 'push');
            app.LoadWAVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWAVButtonPushed, true);
            app.LoadWAVButton.Text = 'Load .WAV';
            app.TriggerNoteButton = uibutton(app.UIFigure, 'push');
            app.TriggerNoteButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerNoteButtonPushed, true);
            app.TriggerNoteButton.Text = 'Trigger Note';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lasynth1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end